classdef CicleBraytonAE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        BacktomenuButton           matlab.ui.control.Button
        MassFlowabLabel            matlab.ui.control.Label
        MassFlowABLabel            matlab.ui.control.Label
        Exergydestroyed2Label      matlab.ui.control.Label
        Exergydestroyed1Label      matlab.ui.control.Label
        ExergyofabLabel            matlab.ui.control.Label
        ExergyofABLabel            matlab.ui.control.Label
        ExergiathatentersLabel     matlab.ui.control.Label
        ExergyattheexitLabel       matlab.ui.control.Label
        TEMPEMKEditField           matlab.ui.control.NumericEditField
        TEMPEMLabel                matlab.ui.control.Label
        MassFlowKgsEditField       matlab.ui.control.NumericEditField
        MassFlowKgsEditFieldLabel  matlab.ui.control.Label
        STARTButton                matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        NetpowerLabel              matlab.ui.control.Label
        Temperature4Label          matlab.ui.control.Label
        Temperature2Label          matlab.ui.control.Label
        PRESS3PaEditField          matlab.ui.control.NumericEditField
        PRESS3PaEditFieldLabel     matlab.ui.control.Label
        TEMP3KEditField            matlab.ui.control.NumericEditField
        TEMP3Label                 matlab.ui.control.Label
        TEMPbKEditField            matlab.ui.control.NumericEditField
        TEMPbKEditFieldLabel       matlab.ui.control.Label
        PRESSaPaEditField          matlab.ui.control.NumericEditField
        PRESSaPaEditFieldLabel     matlab.ui.control.Label
        TEMPaKEditField            matlab.ui.control.NumericEditField
        TEMPaKEditFieldLabel       matlab.ui.control.Label
        PRESSAPaEditField          matlab.ui.control.NumericEditField
        PRESSAPaEditFieldLabel     matlab.ui.control.Label
        TEMPBKEditField            matlab.ui.control.NumericEditField
        TEMPBKEditFieldLabel       matlab.ui.control.Label
        TEMPAKEditField            matlab.ui.control.NumericEditField
        TEMPAKEditFieldLabel       matlab.ui.control.Label
        TEMP1KEditField            matlab.ui.control.NumericEditField
        TEMP1KEditFieldLabel       matlab.ui.control.Label
        PRESS1PaEditField          matlab.ui.control.NumericEditField
        PRESS1PaEditFieldLabel     matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        OK = false
        Errorapp   % Propriedade da janela de erro
        initscene;
    end
    
    methods (Access = private)
        function gotomainmenu(app)
            app.initscene = app1;
        end
        
        %results function
        function results(app,X_entra,X_Gas,X_sai,X_Water,X_D1,X_D2,W_liq,M_AB,M_ab,T2,T4)

            app.ExergiathatentersLabel.Text = "Exergia that enters: " + num2str(X_entra, 6) + " KW";
            app.ExergyattheexitLabel.Text = "Exergy that comes out: " + num2str(X_sai, 6) + " KW";
            app.ExergyofABLabel.Text = "Gas exergy: " + num2str(X_Gas, 6) + " KW";
            app.ExergyofabLabel.Text = "water exergy: " + num2str(X_Water, 6) + " KW";
            app.Exergydestroyed1Label.Text = "Destroyed Exergy 1: " + num2str(X_D1, 6) + " KW";
            app.Exergydestroyed2Label.Text = "Exergy destroyed 2: " + num2str(X_D2, 6) + " KW";
            app.NetpowerLabel.Text = "Power: " + num2str(W_liq, 6) + " KW";
            app.MassFlowABLabel.Text = "Air mass flow: " + num2str(M_AB, 6) + " Kg/s";
            app.MassFlowabLabel.Text = "Water mass flow rate: " + num2str(M_ab, 6) + " Kg/s";
            app.Temperature2Label.Text = "Temperature 2: " + num2str(T2,5) + " K";
            app.Temperature4Label.Text = "Temperature 4: " + num2str(T4,5) + " K";

        end

         % conditional function
        function Condicional(app,T1,T2,T3,T4,P1,P3,TA,TB,Ta,Tb)

            if T1 > T3
                 Showerro(app,"Temperature 1 cannot be greater than temperature 3"); 
                 Resetapp(app);

            elseif P1 > P3
                Showerro(app,"Pressure 1 cannot be greater than pressure 2"); 
                Resetapp(app);

            elseif T2 > T3
                Showerro(app,"Temperature 2 cannot be higher than temperature 3. Decrease temperature 1"); 
                Resetapp(app);

            elseif T1 > T4
                Showerro(app,"Temperature 1 cannot be higher than temperature 4. Decrease temperature 1"); 
                Resetapp(app);

            elseif Ta > Tb
                Showerro(app,"Temperature a cannot be greater than temperature b. Lower the temperature to"); 
                Resetapp(app);

            elseif TB > TA
                Showerro(app,"Temperature B cannot be higher than temperature A. Decrease temperature B"); 
                Resetapp(app);

            else
                app.OK = true;
            end
            
        end

        %function reset values
         function Resetapp(app)
            app.ExergiathatentersLabel.Text = "Exergia that enters: ";
            app.ExergyattheexitLabel.Text = "Exergy that comes out: ";
            app.ExergyofABLabel.Text = "Gas exergy: ";
            app.ExergyofabLabel.Text = "Water exergy: ";
            app.Exergydestroyed1Label.Text = "Destroyed Exergy 1: ";
            app.Exergydestroyed2Label.Text = "Exergy destroyed 2: ";
            app.NetpowerLabel.Text = "Power: ";
            app.MassFlowABLabel.Text = "Air mass flow: ";
            app.MassFlowabLabel.Text = "Water mass flow rate: ";
            app.Temperature2Label.Text = "Temperature 2: ";
            app.Temperature4Label.Text = "Temperature 4: ";

        end
    end
    
    methods (Access = private)
        
         % conditional function 2
        function Condicional_2(app, X_D1,X_D2)

            if X_D1 < 0
                Showerro(app, "The exergy of 3 to 2 is greater than the exergy of A to B. Try changing the properties of 2, 3, A, or B");
                Resetapp(app);

            elseif X_D2 < 0
                Showerro(app, "The exergy of a to b is greater than the exergy of 4 to 1. Try changing the properties of 1, 4, a, b");
                Resetapp(app);
            
            end
            
        end
        
        % error window function
        function Showerro(app,errortext)
            app.Errorapp=brytonError;
            Importdata(app.Errorapp,errortext);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: MassFlowKgsEditField, PRESS1PaEditField, 
        % ...and 11 other components
        function STARTButtonPushed(app, event)
      % input parameters

            T1 = app.TEMP1KEditField.Value;
            P1 = app.PRESS1PaEditField.Value;
            T3 = app.TEMP3KEditField.Value;
            P3 = app.PRESS3PaEditField.Value;
            TA = app.TEMPAKEditField.Value;
            PA = app.PRESSAPaEditField.Value;
            Ta = app.TEMPaKEditField.Value;
            Pa = app.PRESSaPaEditField.Value;
            TB = app.TEMPBKEditField.Value;
            Tb = app.TEMPbKEditField.Value;
            TEM = app.TEMPEMKEditField.Value;
            M = app.MassFlowKgsEditField.Value;

     % Entropy calculations

            S1 = py.CoolProp.CoolProp.PropsSI('S','T',T1,'P',P1,'Air');
            S2 = S1;
            S3 = py.CoolProp.CoolProp.PropsSI('S','T',T3,'P',P3,'Air');
            S4 = S3;
            SA = py.CoolProp.CoolProp.PropsSI('S','T',TA,'P',PA,'Air');
            SB = py.CoolProp.CoolProp.PropsSI('S','T',TB,'P',PA,'Air');
            Sa = py.CoolProp.CoolProp.PropsSI('S','T',Ta,'P',Pa,'Water');
            Sb = py.CoolProp.CoolProp.PropsSI('S','T',Tb,'P',Pa,'Water');

    % Enthalpy calculations

            h1 = py.CoolProp.CoolProp.PropsSI('H','T',T1,'P',P1,'Air');
            h2 = py.CoolProp.CoolProp.PropsSI('H','S',S2,'P',P3,'Air');
            h3 = py.CoolProp.CoolProp.PropsSI('H','T',T3,'P',P3,'Air');
            h4 = py.CoolProp.CoolProp.PropsSI('H','S',S4,'P',P1,'Air');
            hA = py.CoolProp.CoolProp.PropsSI('H','T',TA,'P',PA,'Air');
            hB = py.CoolProp.CoolProp.PropsSI('H','T',TB,'P',PA,'Air');
            ha = py.CoolProp.CoolProp.PropsSI('H','T',Ta,'P',Pa,'Water');
            hb = py.CoolProp.CoolProp.PropsSI('H','T',Tb,'P',Pa,'Water');

    % calculation of temperature 2 and 4

            T2 = py.CoolProp.CoolProp.PropsSI('T', 'S', S1, 'P', P3, 'Air');
            T4 = py.CoolProp.CoolProp.PropsSI('T', 'S', S3, 'P', P1, 'Air');

            Condicional(app,T1,T2,T3,T4,P1,P3,TA,TB,Ta,Tb)
    % Calculation of mass flow
            if app.OK

            M_AB = M * ((h3 - h2) / (hA - hb));
            M_ab = M * ((h1 - h4) / (ha - hb));

    % Exergy Calculation

            X_entra = M_AB * (hA - hB - TEM*(SA -SB));
            X_Gas = M * (h3 - h2 - TEM*(S3 -S2));
            X_sai = M * (h4 - h1 - TEM*(S4 -S1));
            X_Water = M_ab * (hb - ha - TEM*(Sb -Sa));

    % Calculation of destroyed exergy

            X_D1 = X_entra - X_Gas;
            X_D2 = X_sai - X_Water;

    % Net Power Calculation
            
            W_liq = X_entra - X_sai - (X_D1 + X_D2);

           results(app,X_entra,X_Gas,X_sai,X_Water,X_D1,X_D2,W_liq,M_AB,M_ab,T2,T4)
           Condicional_2(app, X_D1,X_D2)
           app.OK = false;
            
            end
        end

        % Button pushed function: BacktomenuButton
        function BacktomenuButtonPushed(app, event)
            gotomainmenu(app);
            app.delete;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {723, 723};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.MassFlowKgsEditFieldLabel = uilabel(app.LeftPanel);
            app.MassFlowKgsEditFieldLabel.Text = 'Mass Flow (Kg/s)';
            app.MassFlowKgsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MassFlowKgsEditField.Limits = [0 Inf];
            app.MassFlowKgsEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.MassFlowKgsEditField.Value = 5;
            app.TEMPEMLabel = uilabel(app.LeftPanel);
            app.TEMPEMLabel.Text = 'TEMP E.M (K)';
            app.TEMPEMKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TEMPEMKEditField.Limits = [0 Inf];
            app.TEMPEMKEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMPEMKEditField.Value = 283;
            app.ExergyattheexitLabel = uilabel(app.LeftPanel);
            app.ExergyattheexitLabel.Text = 'Exergy at the exit';
            app.ExergiathatentersLabel = uilabel(app.LeftPanel);
            app.ExergiathatentersLabel.Text = 'Exergia that enters';
            app.ExergyofABLabel = uilabel(app.LeftPanel);
            app.ExergyofABLabel.Text = 'Exergy of AB';
            app.ExergyofabLabel = uilabel(app.LeftPanel);
            app.ExergyofabLabel.Text = 'Exergy of ab';
            app.Exergydestroyed1Label = uilabel(app.LeftPanel);
            app.Exergydestroyed1Label.Text = 'Exergy destroyed 1';
            app.Exergydestroyed2Label = uilabel(app.LeftPanel);
            app.Exergydestroyed2Label.Text = 'Exergy destroyed 2';
            app.MassFlowABLabel = uilabel(app.LeftPanel);
            app.MassFlowABLabel.Text = 'Mass Flow AB';
            app.MassFlowabLabel = uilabel(app.LeftPanel);
            app.MassFlowabLabel.Text = 'Mass Flow ab';
            app.BacktomenuButton = uibutton(app.LeftPanel, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = 'Back to menu';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CicleBraytonAE.png');
            app.PRESS1PaEditFieldLabel = uilabel(app.RightPanel);
            app.PRESS1PaEditFieldLabel.Text = 'PRESS 1 (Pa)';
            app.PRESS1PaEditField = uieditfield(app.RightPanel, 'numeric');
            app.PRESS1PaEditField.Limits = [10000 100000000];
            app.PRESS1PaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.PRESS1PaEditField.Value = 100000;
            app.TEMP1KEditFieldLabel = uilabel(app.RightPanel);
            app.TEMP1KEditFieldLabel.Text = 'TEMP 1 (K)';
            app.TEMP1KEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMP1KEditField.Limits = [100 2250];
            app.TEMP1KEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMP1KEditField.Value = 300;
            app.TEMPAKEditFieldLabel = uilabel(app.RightPanel);
            app.TEMPAKEditFieldLabel.Text = 'TEMP A (K)';
            app.TEMPAKEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMPAKEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMPAKEditField.Value = 2200;
            app.TEMPBKEditFieldLabel = uilabel(app.RightPanel);
            app.TEMPBKEditFieldLabel.Text = 'TEMP B (K)';
            app.TEMPBKEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMPBKEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMPBKEditField.Value = 600;
            app.PRESSAPaEditFieldLabel = uilabel(app.RightPanel);
            app.PRESSAPaEditFieldLabel.Text = 'PRESS A (Pa)';
            app.PRESSAPaEditField = uieditfield(app.RightPanel, 'numeric');
            app.PRESSAPaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.PRESSAPaEditField.Value = 101000;
            app.TEMPaKEditFieldLabel = uilabel(app.RightPanel);
            app.TEMPaKEditFieldLabel.Text = 'TEMP a (K)';
            app.TEMPaKEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMPaKEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMPaKEditField.Value = 280;
            app.PRESSaPaEditFieldLabel = uilabel(app.RightPanel);
            app.PRESSaPaEditFieldLabel.Text = 'PRESS a (Pa)';
            app.PRESSaPaEditField = uieditfield(app.RightPanel, 'numeric');
            app.PRESSaPaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.PRESSaPaEditField.Value = 101000;
            app.TEMPbKEditFieldLabel = uilabel(app.RightPanel);
            app.TEMPbKEditFieldLabel.Text = 'TEMP b (K)';
            app.TEMPbKEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMPbKEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMPbKEditField.Value = 350;
            app.TEMP3Label = uilabel(app.RightPanel);
            app.TEMP3Label.Text = 'TEMP 3 (K)';
            app.TEMP3KEditField = uieditfield(app.RightPanel, 'numeric');
            app.TEMP3KEditField.Limits = [100 2250];
            app.TEMP3KEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.TEMP3KEditField.Value = 1000;
            app.PRESS3PaEditFieldLabel = uilabel(app.RightPanel);
            app.PRESS3PaEditFieldLabel.Text = 'PRESS 3 (Pa)';
            app.PRESS3PaEditField = uieditfield(app.RightPanel, 'numeric');
            app.PRESS3PaEditField.Limits = [10000 100000000];
            app.PRESS3PaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.PRESS3PaEditField.Value = 1000000;
            app.Temperature2Label = uilabel(app.RightPanel);
            app.Temperature2Label.Text = 'Temperature 2:';
            app.Temperature4Label = uilabel(app.RightPanel);
            app.Temperature4Label.Text = 'Temperature 4:';
            app.NetpowerLabel = uilabel(app.RightPanel);
            app.NetpowerLabel.Text = 'Net power';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CicleBraytonAE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end