classdef CycleBrayton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BacktomenuButton               matlab.ui.control.Button
        TurbineefficiiencyEditField    matlab.ui.control.NumericEditField
        TurbineefficiiencyEditFieldLabel  matlab.ui.control.Label
        CompressorefficiencyEditField  matlab.ui.control.NumericEditField
        CompressorefficiencyEditFieldLabel  matlab.ui.control.Label
        TurbineworkLabel               matlab.ui.control.Label
        Pressure3PaEditField           matlab.ui.control.NumericEditField
        Pressure3Label                 matlab.ui.control.Label
        Temperature3KEditField         matlab.ui.control.NumericEditField
        Temperature3KEditFieldLabel    matlab.ui.control.Label
        InputheatLabel                 matlab.ui.control.Label
        Temperature2KLabel             matlab.ui.control.Label
        CompressorworkLabel            matlab.ui.control.Label
        CyclepowerLabel                matlab.ui.control.Label
        Temperature4KLabel             matlab.ui.control.Label
        Pressure1PaEditField           matlab.ui.control.NumericEditField
        Pressure1PaEditFieldLabel      matlab.ui.control.Label
        Temperature1KEditFieldLabel    matlab.ui.control.Label
        Temperature1KEditField         matlab.ui.control.NumericEditField
        OutputheatLabel                matlab.ui.control.Label
        STARTButton                    matlab.ui.control.Button
        MassflowEditField              matlab.ui.control.NumericEditField
        MassflowEditFieldLabel         matlab.ui.control.Label
        Enthalpy4Label                 matlab.ui.control.Label
        Enthalpy3Label                 matlab.ui.control.Label
        Enthalpy2Label                 matlab.ui.control.Label
        Enthalpy1Label                 matlab.ui.control.Label
        ReverseworkreasonLabel         matlab.ui.control.Label
        EfficiencyLabel                matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        OK = false % Description
        Errorapp
        initscene;
    end

    
    methods (Access = private)
        function gotomainmenu(app)
            app.initscene = app1;
        end
        
        %results function
        function Resultado(app, T2,T4,Wc,Wt,Qe,Qs,h1,h2,h3,h4,Efficiency,bwr,Wciclo)
            app.Temperature2KLabel.Text = " Temperature 2 (K) = " + num2str(T2, 4) + "K";
            app.Temperature4KLabel.Text = " Temperature 4 (K) = " + num2str(T4, 4) + "K";
            app.CompressorworkLabel.Text = " Copressor work: " + num2str(Wc, 5) + "KJ/KG";
            app.TurbineworkLabel.Text = " Turbine work: " + num2str(Wt, 5) + "KJ/KG";
            app.InputheatLabel.Text = " Input heat: " + num2str(Qe, 5) + "KJ/KG";
            app.OutputheatLabel.Text = " Output heat: " + num2str(Qs, 5) + "KJ/KG";
            app.Enthalpy1Label.Text = "Enthalpy 1: " + num2str(h1, 5) + "J/KG";
            app.Enthalpy2Label.Text = "Enthalpy 2: " + num2str(h2, 5) + "J/KG";
            app.Enthalpy3Label.Text = "Enthalpy 3: " + num2str(h3, 5) + "J/KG";
            app.Enthalpy4Label.Text = "Enthalpy 4: " + num2str(h4, 5) + "J/KG";
            app.EfficiencyLabel.Text = " Efficiency : " + num2str(Efficiency, 3) + " %";
            app.ReverseworkreasonLabel.Text = "Reverse work reason : " + num2str(bwr, 3) + " %";
            app.CyclepowerLabel.Text = "Cycle Power : " + num2str(Wciclo, 5) + "KW";
            
        end
        
        % conditional function
        function Condicional(app, T1, T2, T3, T4, P1, P3)
            
            if T1 > T3
                 Showerro(app,"Temperature 1 cannot be greater than temperature 3"); 
                 Resetapp(app);

            elseif P1 > P3
                Showerro(app,"Pressure 1 cannot be greater than pressure 2"); 
                Resetapp(app);

            elseif T2 > T3
                Showerro(app,"Temperature 2 cannot be higher than temperature 3. Decrease temperature 1"); 
                Resetapp(app);

            elseif T1 > T4
                Showerro(app,"Temperature 1 cannot be higher than temperature 4. Decrease temperature 1"); 
                Resetapp(app);

            else
                app.OK = true;
         

            end
        end

        %function reset values
        function Resetapp(app)
            app.Temperature2KLabel.Text = " Temperature 2 (K) = ";
            app.Temperature4KLabel.Text = " Temperature 4 (K) = ";
            app.CompressorworkLabel.Text = " Copressor work: ";
            app.TurbineworkLabel.Text = " Turbine work: ";
            app.InputheatLabel.Text = " Input heat: ";
            app.OutputheatLabel.Text = " Output heat: ";
            app.Enthalpy1Label.Text = "Enthalpy 1: ";
            app.Enthalpy2Label.Text = "Enthalpy 2: ";
            app.Enthalpy3Label.Text = "Enthalpy 3: ";
            app.Enthalpy4Label.Text = "Enthalpy 4: ";
            app.EfficiencyLabel.Text = " Efficiency : ";
            app.ReverseworkreasonLabel.Text = "Reverse work reason : ";
            app.CyclepowerLabel.Text = "Cycle Power : ";


        end
        % conditional function 2
        function Condicional_2(app, Efficiency, bwr)
            if Efficiency > 100
                Showerro(app, "The cycle efficiency for these values is greater than 100%. Try changing these values");
                Resetapp(app);

            elseif Efficiency < 0
                Showerro(app, "The cycle efficiency for these values is below 0%. Try changing these values");
                Resetapp(app);

            elseif bwr > 100
                Showerro(app,"The reverse work ratio for these values is greater than 100%. Try changing these values");
                Resetapp(app);

            elseif bwr < 0
                Showerro(app,"The reverse work ratio for these values is below 0%. Try changing these values");
                Resetapp(app);

            end
        end
        
        % error window function
        function Showerro(app,errortext)
            app.Errorapp=brytonError;
            Importdata(app.Errorapp,errortext);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: MassflowEditField, Pressure1PaEditField, 
        % ...and 4 other components
        function STARTButtonPushed(app, event)
      % input parameters
           T1 = app.Temperature1KEditField.Value;
           T3 = app.Temperature3KEditField.Value;
           P1 = app.Pressure1PaEditField.Value;
           P3 = app.Pressure3PaEditField.Value;
           m = app.MassflowEditField.Value;
           EC = app.CompressorefficiencyEditField.Value;
           ET = app.TurbineefficiiencyEditField.Value;
           
      % Enthalpy and entropy calculation
      
           h1 = py.CoolProp.CoolProp.PropsSI('H', 'T', T1, 'P', P1, 'Air');
           S1 = py.CoolProp.CoolProp.PropsSI('S', 'T', T1, 'P', P1, 'Air');
           T2 = py.CoolProp.CoolProp.PropsSI('T', 'S', S1, 'P', P3, 'Air');
           h2 = py.CoolProp.CoolProp.PropsSI('H', 'T', T2, 'P', P3, 'Air');
           h3 = py.CoolProp.CoolProp.PropsSI('H', 'T', T3, 'P', P3, 'Air');
           S3 = py.CoolProp.CoolProp.PropsSI('S', 'T', T3, 'P', P3, 'Air');
           T4 = py.CoolProp.CoolProp.PropsSI('T', 'S', S3, 'P', P1, 'Air');
           h4 = py.CoolProp.CoolProp.PropsSI('H', 'T', T4, 'P', P1, 'Air');
           

          Condicional(app, T1, T2, T3, T4, P1, P3);

      % Calculation of work and heat
         if app.OK 

           Wt = m * (h3 - h4) * (ET / 100) / 1000;
           Wc = m * (h2 - h1) * (EC / 100) / 1000;
           Qs = m * (h4 - h1) / 1000;
           Qe = m * (h3 - h2) / 1000;
           
      % Calculation of cycle thermal efficiency
      
           Efficiency = (((Wt/m) - (Wc/m)) / (Qe/m)) * 100;
           
      % Reverse work ratio calculation
      
           bwr = (Wc/m)/(Wt/m) * 100;
      
      % Calculation of net power developed
      
           Wciclo = m * ((Wt/m) - (Wc/m));
      
           Resultado(app, T2,T4,Wc,Wt,Qe,Qs,h1,h2,h3,h4,Efficiency,bwr,Wciclo);
           Condicional_2(app, Efficiency, bwr)
           app.OK = false;
             
         end
        end

        % Callback function
        function CompressorefficiencySliderValueChanged(app, event)
 
        end

        % Callback function
        function TurbineefficiiencySliderValueChanged(app, event)

        end

        % Value changed function: CompressorefficiencyEditField
        function CompressorefficiencyEditFieldValueChanged(app, event)
            EC = app.CompressorefficiencyEditField.Value;
            
        end

        % Value changed function: TurbineefficiiencyEditField
        function TurbineefficiiencyEditFieldValueChanged(app, event)
            ET = app.TurbineefficiiencyEditField.Value;
            
        end

        % Button pushed function: BacktomenuButton
        function BacktomenuButtonPushed(app, event)
            gotomainmenu(app);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CicleBrayton.jpg');
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Efficiency';
            app.ReverseworkreasonLabel = uilabel(app.UIFigure);
            app.ReverseworkreasonLabel.Text = 'Reverse work reason';
            app.Enthalpy1Label = uilabel(app.UIFigure);
            app.Enthalpy1Label.Text = 'Enthalpy 1';
            app.Enthalpy2Label = uilabel(app.UIFigure);
            app.Enthalpy2Label.Text = 'Enthalpy 2';
            app.Enthalpy3Label = uilabel(app.UIFigure);
            app.Enthalpy3Label.Text = 'Enthalpy 3';
            app.Enthalpy4Label = uilabel(app.UIFigure);
            app.Enthalpy4Label.Text = 'Enthalpy 4';
            app.MassflowEditFieldLabel = uilabel(app.UIFigure);
            app.MassflowEditFieldLabel.Text = 'Mass flow';
            app.MassflowEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassflowEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.MassflowEditField.Value = 10;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.OutputheatLabel = uilabel(app.UIFigure);
            app.OutputheatLabel.Text = 'Output heat :';
            app.Temperature1KEditField = uieditfield(app.UIFigure, 'numeric');
            app.Temperature1KEditField.Limits = [100 3000];
            app.Temperature1KEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.Temperature1KEditField.Value = 300;
            app.Temperature1KEditFieldLabel = uilabel(app.UIFigure);
            app.Temperature1KEditFieldLabel.Text = 'Temperature 1 (K)';
            app.Pressure1PaEditFieldLabel = uilabel(app.UIFigure);
            app.Pressure1PaEditFieldLabel.Text = 'Pressure 1 (Pa)';
            app.Pressure1PaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Pressure1PaEditField.Limits = [100000 3000000];
            app.Pressure1PaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.Pressure1PaEditField.Value = 100000;
            app.Temperature4KLabel = uilabel(app.UIFigure);
            app.Temperature4KLabel.Text = 'Temperature 4 (K)';
            app.CyclepowerLabel = uilabel(app.UIFigure);
            app.CyclepowerLabel.Text = 'Cycle power:';
            app.CompressorworkLabel = uilabel(app.UIFigure);
            app.CompressorworkLabel.Text = 'Compressor work:';
            app.Temperature2KLabel = uilabel(app.UIFigure);
            app.Temperature2KLabel.Text = 'Temperature 2 (K)';
            app.InputheatLabel = uilabel(app.UIFigure);
            app.InputheatLabel.Text = 'Input heat :';
            app.Temperature3KEditFieldLabel = uilabel(app.UIFigure);
            app.Temperature3KEditFieldLabel.Text = 'Temperature 3 (K)';
            app.Temperature3KEditField = uieditfield(app.UIFigure, 'numeric');
            app.Temperature3KEditField.Limits = [100 3000];
            app.Temperature3KEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.Temperature3KEditField.Value = 1000;
            app.Pressure3Label = uilabel(app.UIFigure);
            app.Pressure3Label.Text = 'Pressure 3 (Pa)';
            app.Pressure3PaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Pressure3PaEditField.Limits = [100000 30000000];
            app.Pressure3PaEditField.ValueChangedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.Pressure3PaEditField.Value = 1000000;
            app.TurbineworkLabel = uilabel(app.UIFigure);
            app.TurbineworkLabel.Text = 'Turbine work:';
            app.CompressorefficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.CompressorefficiencyEditFieldLabel.Text = 'Compressor efficiency %';
            app.CompressorefficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompressorefficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @CompressorefficiencyEditFieldValueChanged, true);
            app.CompressorefficiencyEditField.Value = 100;
            app.TurbineefficiiencyEditFieldLabel = uilabel(app.UIFigure);
            app.TurbineefficiiencyEditFieldLabel.Text = 'Turbine efficiiency %';
            app.TurbineefficiiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurbineefficiiencyEditField.ValueChangedFcn = createCallbackFcn(app, @TurbineefficiiencyEditFieldValueChanged, true);
            app.TurbineefficiiencyEditField.Value = 100;
            app.BacktomenuButton = uibutton(app.UIFigure, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = 'Back to menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CycleBrayton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end