classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TeacheradvisorFranciscoMarcelinoAlmeidadeAraujoLabel  matlab.ui.control.Label
        BraytonExergyButton  matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        BraytonCycleButton   matlab.ui.control.Button
        RankineExergyButton  matlab.ui.control.Button
        Label                matlab.ui.control.Label
        RankineCycleButton   matlab.ui.control.Button
        Image                matlab.ui.control.Image
        EnergyandexergyanalysisofRankineandBraytonCyclesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        errorwindow;
        rankineapp = ""; 
        exergyofrankineapp = "";
        braytonapp = "";
        exergyofbraytonapp = "";
    end
    
    methods (Access = public)
        function throwErr(app, text)
            app.errorwindow = rankineErrorMessage; 
            import_data(app.errorwindow, text);
        end
        
        function gotorankine(app)
            app.rankineapp = mainScreen;
            
        end

        function gotoexergyrankine(app)
            app.exergyofrankineapp = exergy;
            
        end

        function gotobrayton(app)
            app.braytonapp = CycleBrayton;
            
        end

        function gotoexergybrayton(app)
            app.exergyofbraytonapp = CicleBraytonAE;
            
        end
        
        % Called at the startup, verifies if the python is installed and
        % the version matches the right ones
        function checkPythonVersion(app)
            pe = pyenv;
            supportedVersions = ["3.10" "3.9" "3.11"];
            
            if ~(ismember(pe.Version, supportedVersions))
                app.throwErr("Please insure that your python is installed " + ...
                    "and it's version is between 3.9 and 3.11");
                disp("Python Env: ");
                disp(pe);
                app.delete;
            end
        end

        % Verifys if the coolprop is installed and in case of not have been
        % installed installs coolprop.
        function checkCoolPropInstalation(app)
                try
                    CoolProp = py.importlib.import_module('CoolProp');
                catch e
                    disp("CoolProp not Founded, trying to install");
                    [v,e] = pyversion;
                    app.delete
                    exitCode = system([e,' -m pip install --user -U CoolProp']);
                    if exitCode == 0
                        app1;
                    else
                        app.throwErr("Something happened while the program was trying to install coolprop")
                    end
                end

        end

    end
    
    methods (Access = private)
        
        function results = func7(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.checkPythonVersion();
            app.checkCoolPropInstalation();
        end

        % Button pushed function: RankineCycleButton
        function RankineCycleButtonPushed(app, event)
            gotorankine(app);
            app.delete;
        end

        % Button pushed function: RankineExergyButton
        function RankineExergyButtonPushed(app, event)
            gotoexergyrankine(app);
            app.delete;
        end

        % Button pushed function: BraytonCycleButton
        function BraytonCycleButtonPushed(app, event)
            gotobrayton(app);
            app.delete;
        end

        % Button pushed function: BraytonExergyButton
        function BraytonExergyButtonPushed(app, event)
            gotoexergybrayton(app);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnergyandexergyanalysisofRankineandBraytonCyclesLabel = uilabel(app.UIFigure);
            app.EnergyandexergyanalysisofRankineandBraytonCyclesLabel.Text = 'Energy and exergy analysis of Rankine and Brayton Cycles';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpeg');
            app.RankineCycleButton = uibutton(app.UIFigure, 'push');
            app.RankineCycleButton.ButtonPushedFcn = createCallbackFcn(app, @RankineCycleButtonPushed, true);
            app.RankineCycleButton.Text = 'Rankine Cycle';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This is a steam power cycle, that is, it is a cycle that presents a fluid in the vapor state, which generates energy when passing through the turbine.';
            app.RankineExergyButton = uibutton(app.UIFigure, 'push');
            app.RankineExergyButton.ButtonPushedFcn = createCallbackFcn(app, @RankineExergyButtonPushed, true);
            app.RankineExergyButton.Text = 'Rankine Exergy';
            app.BraytonCycleButton = uibutton(app.UIFigure, 'push');
            app.BraytonCycleButton.ButtonPushedFcn = createCallbackFcn(app, @BraytonCycleButtonPushed, true);
            app.BraytonCycleButton.Text = 'Brayton Cycle';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'The Brayton cycle is a thermodynamic cycle widely used in jet engines, gas turbines and gas-fired electrical power plants.';
            app.BraytonExergyButton = uibutton(app.UIFigure, 'push');
            app.BraytonExergyButton.ButtonPushedFcn = createCallbackFcn(app, @BraytonExergyButtonPushed, true);
            app.BraytonExergyButton.Text = 'Brayton Exergy';
            app.TeacheradvisorFranciscoMarcelinoAlmeidadeAraujoLabel = uilabel(app.UIFigure);
            app.TeacheradvisorFranciscoMarcelinoAlmeidadeAraujoLabel.Text = {'Teacher advisor: Francisco Marcelino Almeida de Araujo'; 'Students: Jose Heitor Nery Viana'; '     Ryan Faustino Carvalho'; '     Bianca Almeida de Oliveira Bezerra'; '      Wyllyames de Sousa Carvalho'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end