classdef exergy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PumpLabel                     matlab.ui.control.Label
        CondenserLabel                matlab.ui.control.Label
        TurbineLabel                  matlab.ui.control.Label
        EvaporatorLabel_2             matlab.ui.control.Label
        XoLabel                       matlab.ui.control.Label
        XiLabel                       matlab.ui.control.Label
        WpLabel                       matlab.ui.control.Label
        WtLabel                       matlab.ui.control.Label
        XsLabel                       matlab.ui.control.Label
        XeLabel                       matlab.ui.control.Label
        Point3                        matlab.ui.container.Panel
        Point3OutLabel                matlab.ui.control.Label
        Title3                        matlab.ui.control.Label
        Pressure3                     matlab.ui.control.NumericEditField
        PressurePaEditField_5Label_4  matlab.ui.control.Label
        DestroyedExergy_2             matlab.ui.container.Panel
        DestroyedExergyLabel_2        matlab.ui.control.Label
        Xd_2                          matlab.ui.control.Label
        PointBl                       matlab.ui.container.Panel
        PointBlOutLabel               matlab.ui.control.Label
        Bl                            matlab.ui.control.Label
        TempBl                        matlab.ui.control.NumericEditField
        TempKEditField_6Label_4       matlab.ui.control.Label
        Point2                        matlab.ui.container.Panel
        Point2OutLabel                matlab.ui.control.Label
        Title2                        matlab.ui.control.Label
        Pressure2                     matlab.ui.control.NumericEditField
        PressurePaEditField_5Label_3  matlab.ui.control.Label
        PointAl                       matlab.ui.container.Panel
        PointAlOutLabel               matlab.ui.control.Label
        Al                            matlab.ui.control.Label
        TempAl                        matlab.ui.control.NumericEditField
        TempKEditField_6Label_3       matlab.ui.control.Label
        DestroyedExergy               matlab.ui.container.Panel
        DestroyedExergyLabel          matlab.ui.control.Label
        Xd                            matlab.ui.control.Label
        PointA                        matlab.ui.container.Panel
        PointAOutLabel                matlab.ui.control.Label
        A                             matlab.ui.control.Label
        TempA                         matlab.ui.control.NumericEditField
        TempKEditField_6Label_2       matlab.ui.control.Label
        PointB                        matlab.ui.container.Panel
        PointBOutLabel                matlab.ui.control.Label
        B                             matlab.ui.control.Label
        TempB                         matlab.ui.control.NumericEditField
        TempKEditField_6Label         matlab.ui.control.Label
        Point4                        matlab.ui.container.Panel
        Point4OutLabel                matlab.ui.control.Label
        Title4                        matlab.ui.control.Label
        Pressure4                     matlab.ui.control.NumericEditField
        PressurePaEditField_5Label_2  matlab.ui.control.Label
        Point1                        matlab.ui.container.Panel
        Temp1                         matlab.ui.control.NumericEditField
        PressurePaEditField_5Label_5  matlab.ui.control.Label
        Title1                        matlab.ui.control.Label
        Pressure1                     matlab.ui.control.NumericEditField
        PressurePaEditField_5Label    matlab.ui.control.Label
        InputsPanel                   matlab.ui.container.Panel
        ReturnToMenu                  matlab.ui.control.Button
        FluidTypeKnobLabel            matlab.ui.control.Label
        AmbientPressure               matlab.ui.control.NumericEditField
        TempKEditField_3Label_2       matlab.ui.control.Label
        AmbientTemp                   matlab.ui.control.NumericEditField
        TempKEditField_6Label_5       matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        ExergeticefficiencyofthecondenserLabel  matlab.ui.control.Label
        ExergeticefficiencyoftheevaporatorLabel  matlab.ui.control.Label
        GasesfromcombustionontheevaporatorAirLabel  matlab.ui.control.Label
        CoolingfluidonthecondenserWaterLabel  matlab.ui.control.Label
        MaxFlowRate                   matlab.ui.control.NumericEditField
        TempKEditField_3Label         matlab.ui.control.Label
        FluidSelected                 matlab.ui.control.Label
        FluidofworkKnob               matlab.ui.control.DiscreteKnob
        FluidofworkKnobLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)

        % List to store temperature values for points A and B
        exergy_temp_values = []; 
        % List to store temperature values for points A' and B'
        exergy_templ_values = [];
   % List to store temperature values and pressure values for points 1 to 4
        cycle_pressure_values = [];
        cycle_temp_values = [];

        % Used for store the fase of a specific point
        % Al and Bl mean A' and B' 
        checkPhase_1 = ""; 
        Phase_3 = "";
        Phase_2 = "";
        Phase_4 = ""; 
        Phase_A = "";
        Phase_B = "";
        Phase_Al = "";
        Phase_Bl = "";

        canRun = false; % control when the app can run
        current_fluid = ""; % fluid selected in the app

        % Pressure and temperature limits for points 1 to 4
        P_critic = 0;
        P_min = 0;
        T_critic = 0;

        % Entropy Values:
        H_1 = 0;
        H_2 = 0;
        H_3 = 0;
        H_4 = 0;

        % Temperature Values:
        T_4 = 0;
        T_3 = 0;
        T_2 = 0;

        % Entalpy Values:
        S_1 = 0;  
        S_3 = 0;

        % Exergy Analisis:
        ambient_pressure = 0; 
        ambient_temp = 0;
        % Temperature limits for the points A' and B'
        T_MIN_LIQ = 0;
        T_MAX_LIQ = 0;
        %%%%%%%%%%%%%%%
        FLUID_OF_EVAPORATOR = 'Air';
        FLUID_OF_CONDENSER = 'Water';
        % Temperature minimal for the points A and B
        T_MAX_COMBUSTION_GAS = 0;
        %%%%%%%%%%%%%%
        T_A = 0;
        T_B = 0;
        T_Al = 0;
        T_Bl = 0;
        HA = 0;
        HB = 0;
        HBl = 0;
        HAl = 0;
        SA = 0;
        SB = 0;
        SAl = 0;
        SBl = 0;
        % Limits for the pressure and temperature for the ambient 
        Aplimitinf = 0;
        Aplimitsup = 0;
        Atlimitinf = 0;
    end

    properties (Access = private)
        errorwindow; 
        initscene;
    end

    methods (Access = public)

        %% Throws a custom error message based on a given text
        function throw_err(app, text)
            app.errorwindow = rankineErrorMessage; 
            import_data(app.errorwindow, text);

        end

        function gotomainmenu(app)
            app.initscene = app1;
        end
        
        %% Adjust the global limits for the app
        function setup_limits_of_the_exergy(app)
             % Setupping the cycle limits:
             app.P_critic = py.CoolProp.CoolProp.PropsSI('Pcrit', app.current_fluid);
             app.P_min = round(py.CoolProp.CoolProp.PropsSI('pmin', app.current_fluid), 2);
             app.T_critic = py.CoolProp.CoolProp.PropsSI('Tcrit', app.current_fluid);

             % Setup presure limits
             for pressure = app.cycle_pressure_values
                 pressure.Limits = [app.P_min app.P_critic];
             end
             
             % the temperature that can be set
             app.Temp1.Limits = [app.T_critic inf];

            % Discovering the global limits of temperature and pressure:
            app.T_MIN_LIQ = py.CoolProp.CoolProp.PropsSI('Tmin', app.FLUID_OF_CONDENSER);
            app.T_MAX_LIQ = py.CoolProp.CoolProp.PropsSI('Tcrit', app.FLUID_OF_CONDENSER);
            app.T_MAX_COMBUSTION_GAS = py.CoolProp.CoolProp.PropsSI('Tcrit', app.FLUID_OF_EVAPORATOR);
            app.Aplimitinf = py.CoolProp.CoolProp.PropsSI('pmin', 'Water');
            app.Aplimitsup = py.CoolProp.CoolProp.PropsSI('Pcrit', 'Water');
            app.Atlimitinf = py.CoolProp.CoolProp.PropsSI('Tmin', 'Water');
          
            % Adding the input limits:
            app.AmbientPressure.Limits = [app.Aplimitinf app.Aplimitsup];
            app.TempA.Limits = [app.T_MAX_COMBUSTION_GAS inf];
            app.TempB.Limits = [app.T_MAX_COMBUSTION_GAS inf];
            app.TempAl.Limits = [app.T_MIN_LIQ app.T_MAX_LIQ];
            app.TempBl.Limits = [app.T_MIN_LIQ app.T_MAX_LIQ];
            app.AmbientTemp.Limits = [app.Atlimitinf inf];


        end

        %% Runs the cicle
        % This function does all the calculation of the cycle and the
        % exergy analisis
        function run_exergy(app)
            % Input Parameters
            app.T_A = app.TempA.Value;
            app.T_B = app.TempB.Value;
            app.T_Al = app.TempAl.Value;
            app.T_Bl = app.TempBl.Value;
            app.ambient_temp = app.AmbientTemp.Value;
            app.ambient_pressure = app.AmbientPressure.Value;
            try
                % Point A
                app.HA = py.CoolProp.CoolProp.PropsSI('H','T', app.T_A, 'P',app.ambient_pressure ,app.FLUID_OF_EVAPORATOR);
                app.SA = py.CoolProp.CoolProp.PropsSI('S','T', app.T_A, 'P',app.ambient_pressure ,app.FLUID_OF_EVAPORATOR);
                app.Phase_A = py.CoolProp.CoolProp.PhaseSI('P', app.AmbientPressure.Value, 'T', app.T_A, app.FLUID_OF_EVAPORATOR);
                % Point B
                app.HB = py.CoolProp.CoolProp.PropsSI('H','T', app.T_B, 'P',app.ambient_pressure ,app.FLUID_OF_EVAPORATOR);
                app.SB = py.CoolProp.CoolProp.PropsSI('S','T', app.T_B, 'P',app.ambient_pressure ,app.FLUID_OF_EVAPORATOR);
                app.Phase_B = py.CoolProp.CoolProp.PhaseSI('P', app.AmbientPressure.Value, 'T', app.T_B, app.FLUID_OF_EVAPORATOR);
                % Point Al
                app.HAl = py.CoolProp.CoolProp.PropsSI('H','T', app.T_Al, 'P',app.ambient_pressure ,app.FLUID_OF_CONDENSER);
                app.SAl = py.CoolProp.CoolProp.PropsSI('S','T', app.T_Al, 'P',app.ambient_pressure ,app.FLUID_OF_CONDENSER);
                app.Phase_Al = py.CoolProp.CoolProp.PhaseSI('P', app.AmbientPressure.Value, 'T', app.T_Al, app.FLUID_OF_CONDENSER);
                % Point Bl
                app.HBl = py.CoolProp.CoolProp.PropsSI('H','T', app.T_Bl, 'P',app.ambient_pressure ,app.FLUID_OF_CONDENSER);
                app.SBl = py.CoolProp.CoolProp.PropsSI('S','T', app.T_Bl, 'P',app.ambient_pressure ,app.FLUID_OF_CONDENSER);
                app.Phase_Bl = py.CoolProp.CoolProp.PhaseSI('P', app.AmbientPressure.Value, 'T', app.T_Bl, app.FLUID_OF_CONDENSER);
                
                % Point 1
                app.checkPhase_1 = string(py.CoolProp.CoolProp.PhaseSI('P', app.Pressure1.Value, 'T', app.Temp1.Value, app.current_fluid));
                app.H_1 = py.CoolProp.CoolProp.PropsSI('H', 'P', app.Pressure1.Value, 'T', app.Temp1.Value, app.current_fluid);
                app.S_1 = py.CoolProp.CoolProp.PropsSI('S', 'P', app.Pressure1.Value, 'T', app.Temp1.Value, app.current_fluid);
                
                % Point 2
                app.H_2 = py.CoolProp.CoolProp.PropsSI('H', 'P', app.Pressure2.Value, 'S', app.S_1, app.current_fluid);
                app.T_2 = py.CoolProp.CoolProp.PropsSI('T', 'P', app.Pressure2.Value, 'S', app.S_1, app.current_fluid);
                app.Phase_2 = py.CoolProp.CoolProp.PhaseSI('P', app.Pressure2.Value, 'S', app.S_1, app.current_fluid);
                
                % Point 3
                app.H_3 = py.CoolProp.CoolProp.PropsSI('H', 'P', app.Pressure3.Value, 'Q', 0, app.current_fluid);
                app.S_3 = py.CoolProp.CoolProp.PropsSI('S', 'P', app.Pressure3.Value, 'Q', 0, app.current_fluid);
                app.T_3 = py.CoolProp.CoolProp.PropsSI('T', 'P', app.Pressure3.Value, 'S', app.S_3, app.current_fluid);
                app.Phase_3 = py.CoolProp.CoolProp.PhaseSI('P', app.Pressure3.Value, 'T', app.T_3, app.current_fluid);
                
                % Point 4
                app.H_4 = py.CoolProp.CoolProp.PropsSI('H', 'P', app.Pressure4.Value, 'S', app.S_3, app.current_fluid);
                app.T_4 = py.CoolProp.CoolProp.PropsSI('T', 'P', app.Pressure4.Value, 'S', app.S_3, app.current_fluid);
                app.Phase_4 = py.CoolProp.CoolProp.PhaseSI('P', app.Pressure4.Value, 'S', app.S_3, app.current_fluid);
                check_exergy_inputs(app);


            catch ERR
                throw_err(app, ERR)
            end
        end
        
        %% This function resets the label values on the screen
        function reseting_values(app)
            % The variable X means exergy on the process 
            app.XeLabel.Text = ''; % Exergy from the A to B
            app.XsLabel.Text = ''; % Exergy from the 4 to 1
            app.XiLabel.Text = ''; % Exergy from the 2 to 3
            app.XoLabel.Text = ''; % Exergy from the A' to B'
            app.ExergeticefficiencyofthecondenserLabel.Text = "";
            app.ExergeticefficiencyoftheevaporatorLabel.Text = "";
% Each label bellow corresponding the propierties of the state for each point
            app.Point4OutLabel.Text = "";
            app.Point3OutLabel.Text = "";
            app.Point2OutLabel.Text = "";
            app.PointAOutLabel.Text = "";
            app.PointBOutLabel.Text = "";
            app.PointAlOutLabel.Text = "";
            app.PointBlOutLabel.Text = "";
            % Destroyed exergy is the difference Xe - Xs and Xi - Xs
            app.DestroyedExergyLabel.Text = "";
            app.DestroyedExergyLabel_2.Text = "";
            %%%%%%%%%%
            app.WpLabel.Text = "";
            app.WtLabel.Text = "";

        end

        %% Encharged to show the result values
        function show_exergy(app, Xe, Xs, Xi, Xo, Xdestroyed, Xdestroyed_2, Wp, Wt, Eff_1, Eff_2)
 
            if contains(string(app.Phase_3), "unknown")
                app.Phase_3 = "Saturated Liquid";
                % Discvered by trial and error that when the unkwoun means
                % that the fluid is in the saturated liquid state
            end
            app.XeLabel.Text = " Xe:  = " + num2str(Xe, "%.2f") + "KJ/s";
            app.XsLabel.Text = " Xs:  = " + num2str(Xs, "%.2f") + "KJ/s";
            app.XiLabel.Text = " Xi:  = " + num2str(Xi, "%.2f") + "KJ/s";
            app.XoLabel.Text = " Xo:  = " + num2str(Xo, "%.2f") + "KJ/s";
            app.WpLabel.Text = "Wp = " + num2str(Wp, "%.2f") + "KJ/s";
            app.WtLabel.Text = "Wt = " + num2str(Wt, "%.2f") + "KJ/s";
            app.Point2OutLabel.Text = "Phase: " + string(app.Phase_2) + newline + "Temp: " + num2str(app.T_2, "%.2f") + "K";
            app.Point3OutLabel.Text = "Phase: " + string(app.Phase_3) + newline + "Temp: " + num2str(app.T_3, "%.2f") + "K";
            app.Point4OutLabel.Text = "Phase: " + string(app.Phase_4) + newline + "Temp: " + num2str(app.T_4, "%.2f") + "K";
            app.PointAOutLabel.Text = "Phase: " + string(app.Phase_A);
            app.PointBOutLabel.Text = "Phase: " + string(app.Phase_B);
            app.PointAlOutLabel.Text = "Phase: " + string(app.Phase_Al);
            app.PointBlOutLabel.Text = "Phase: " + string(app.Phase_Bl);
            app.DestroyedExergyLabel.Text = "Destroyed Exergy: " + num2str(Xdestroyed, "%.2f") + "KJ/s";
            app.DestroyedExergyLabel_2.Text = "Destroyed Exergy: " + num2str(Xdestroyed_2, "%.2f") + "KJ/s";
            app.ExergeticefficiencyoftheevaporatorLabel.Text = "Exergetic efficiency of the evaporator: " + num2str(Eff_1, "%.2f") + "%";
            app.ExergeticefficiencyofthecondenserLabel.Text = "Exergetic efficiency of the condenser: " + num2str(Eff_2, "%.2f") + "%";
        end


        function check_exergy_inputs(app)
            %  Point 1 should supercritical gas
            if strcmp(app.checkPhase_1, "supercritical_gas") == 0
                throw_err(app, "Point 1 is not in superheated steam state.");
            % Pressure 1 should greater than Pressure 2
            elseif app.Pressure2.Value >= app.Pressure1.Value 
                throw_err(app, "Pressure in point 2 is greater or equal than point 1.");
            % Temperature in the point A should greater point B
            elseif app.T_B >= app.T_A
                throw_err(app, "Temperature in point B is greater or equal than point A.")
            % Temperature in the point A' should greater point B' 
            elseif app.T_Al >= app.T_Bl
                throw_err(app, "Temperature in point A' is greater or equal than point B'.");
            % Can run
            else
                app.canRun = true;
            end
        end

        function check_exergy_and_efficiency(app,Xi,Xe,Xs,Xo,Eff_2,Eff_1)
            % Any varyable cannot be negative
            if Xi < 0
                throw_err(app, "Exergy from 2 to 3 cannot be negative. Try increasing the pressure by 2 or 3.");
                reseting_values(app);

            elseif Xs < 0
                throw_err(app, "Exergy from 4 to 1 cannot be negative. Try changing the temperature value from 1");
                reseting_values(app);
            elseif Xe < 0
                throw_err(app, "Exergy from A to B cannot be negative. Try changing the temperature values from A or B");
                reseting_values(app);

            elseif Xo < 0
                throw_err(app, "Exergy from A` to B` cannot be negative. Try changing the values at point A` or B`");
                reseting_values(app);
            % Any efficiency cannot be greater than 100
            elseif Eff_2 > 100
                throw_err(app, "Exergy of a' to b' is greater than the exergy of 2 to 3. Try increasing the pressure from 2 to 3.");
                reseting_values(app);

            elseif Eff_1 > 100
                throw_err(app, "Exergy of 1 to 4 is greater than the exergy of a to b. Try changing the values at point A, B or 1");
                reseting_values(app);

            end
    end 
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [150 8 1310 645];
            app.cycle_pressure_values = [app.Pressure1  app.Pressure2  app.Pressure3  app.Pressure4];
            app.cycle_temp_values = [app.Temp1  app.T_2  app.T_3  app.T_4];
            app.exergy_temp_values = [app.TempA  app.TempB];
            app.exergy_templ_values = [app.TempAl  app.TempBl];
            reseting_values(app);
            app.UIFigure.Name = "EXERGY OF THE RANKINE CYCLE";
            app.current_fluid = app.FluidofworkKnob.Value;
            app.FluidSelected.Text = "Valor selecionado: " + num2str(app.current_fluid);
            app.RunButton.Icon = "";
            setup_limits_of_the_exergy(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            reseting_values(app);
            run_exergy(app);
            Vmax = app.MaxFlowRate.Value;
            MAXFLOWRATE_to_AB = Vmax *((app.H_1 - app.H_4)/(app.HA - app.HB)); 
            MAXFLOWRATE_to_AlBl = Vmax*((app.H_2 - app.H_3)/(app.HBl - app.HAl));
          
            if app.canRun
                % Calculating the Heat and Work
                Wt = Vmax * (app.H_1 - app.H_2)/ 1000; % KJ/s
                Wp = Vmax * (app.H_4 - app.H_3)/ 1000; % KJ/s
                Xe = MAXFLOWRATE_to_AB*((app.HA - app.HB) -((app.ambient_temp)*(app.SA - app.SB)))/1000; %KJ/s
                Xs = Vmax*((app.H_1 - app.H_4) -((app.ambient_temp)*(app.S_1 - app.S_3)))/1000; %KJ/s
                Xi = Vmax*((app.H_2 - app.H_3) -((app.ambient_temp)*(app.S_1 - app.S_3)))/1000; %KJ/s
                Xo = MAXFLOWRATE_to_AlBl*((app.HBl - app.HAl) -((app.ambient_temp)*(app.SBl - app.SAl)))/1000; %KJ/s
                Xdestroyed = (Xe - Xs); %KJ/s
                Xdestroyed_2 = (Xi - Xo); %KJ/s
                Eff_1 = (Xs/Xe)*(100); % range of the 0 to 100
                Eff_2 = (Xo/Xi)*(100); % range of the 0 to 100
                show_exergy(app, Xe, Xs, Xi, Xo, Xdestroyed, Xdestroyed_2, Wp, Wt, Eff_1, Eff_2);

            end
            check_exergy_and_efficiency(app,Xi,Xe,Xs,Xo,Eff_2,Eff_1)
            app.canRun = false;
        end

        % Value changed function: Pressure3
        function Pressure3ValueChanged(app, event)
            value = app.Pressure3.Value;
            app.Pressure2.Value = value;            
        end

        % Value changed function: Pressure2
        function Pressure2ValueChanged(app, event)
            value = app.Pressure2.Value;
            app.Pressure3.Value = value;
        end

        % Value changed function: Pressure4
        function Pressure4ValueChanged(app, event)
            value = app.Pressure4.Value;
            app.Pressure1.Value = value;
        end

        % Value changed function: Pressure1
        function Pressure1ValueChanged(app, event)
            value = app.Pressure1.Value;
            app.Pressure4.Value = value;
        end

        % Value changed function: FluidofworkKnob
        function FluidofworkKnobValueChanged(app, event)
            value = app.FluidofworkKnob.Value;
            app.current_fluid = value;
            setup_limits_of_the_exergy(app);
            app.FluidSelected.Text = "Valor selecionado: " + num2str(value);
        end

        % Button pushed function: ReturnToMenu
        function ReturnToMenuButtonPushed(app, event)
            gotomainmenu(app);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ExergeticalAnalysis.jpeg');
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.FluidofworkKnobLabel = uilabel(app.InputsPanel);
            app.FluidofworkKnobLabel.Text = 'Fluid of work';
            app.FluidofworkKnob = uiknob(app.InputsPanel, 'discrete');
            app.FluidofworkKnob.Items = {'R11', 'R245fa', 'Water'};
            app.FluidofworkKnob.ValueChangedFcn = createCallbackFcn(app, @FluidofworkKnobValueChanged, true);
            app.FluidofworkKnob.Value = 'R11';
            app.FluidSelected = uilabel(app.InputsPanel);
            app.FluidSelected.Text = 'Valor Selecionado: R11';
            app.TempKEditField_3Label = uilabel(app.InputsPanel);
            app.TempKEditField_3Label.Text = 'Maximum Flow Rate';
            app.MaxFlowRate = uieditfield(app.InputsPanel, 'numeric');
            app.MaxFlowRate.ValueDisplayFormat = '%.2f';
            app.MaxFlowRate.Value = 300;
            app.CoolingfluidonthecondenserWaterLabel = uilabel(app.InputsPanel);
            app.CoolingfluidonthecondenserWaterLabel.Text = 'Cooling fluid on the condenser : Water';
            app.GasesfromcombustionontheevaporatorAirLabel = uilabel(app.InputsPanel);
            app.GasesfromcombustionontheevaporatorAirLabel.Text = 'Gases from combustion on the evaporator : Air';
            app.ExergeticefficiencyoftheevaporatorLabel = uilabel(app.InputsPanel);
            app.ExergeticefficiencyoftheevaporatorLabel.Text = 'Exergetic efficiency of the evaporator:';
            app.ExergeticefficiencyofthecondenserLabel = uilabel(app.InputsPanel);
            app.ExergeticefficiencyofthecondenserLabel.Text = 'Exergetic efficiency of the condenser:';
            app.RunButton = uibutton(app.InputsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.TempKEditField_6Label_5 = uilabel(app.InputsPanel);
            app.TempKEditField_6Label_5.Text = 'Ambient Temperature (K)';
            app.AmbientTemp = uieditfield(app.InputsPanel, 'numeric');
            app.AmbientTemp.ValueDisplayFormat = '%.2f';
            app.AmbientTemp.Value = 298;
            app.TempKEditField_3Label_2 = uilabel(app.InputsPanel);
            app.TempKEditField_3Label_2.Text = 'Ambient Pressure (Pa)';
            app.AmbientPressure = uieditfield(app.InputsPanel, 'numeric');
            app.AmbientPressure.ValueDisplayFormat = '%.2f';
            app.AmbientPressure.Value = 100000;
            app.FluidTypeKnobLabel = uilabel(app.InputsPanel);
            app.FluidTypeKnobLabel.Text = 'Rankine Cycle';
            app.ReturnToMenu = uibutton(app.InputsPanel, 'push');
            app.ReturnToMenu.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMenuButtonPushed, true);
            app.ReturnToMenu.Text = 'Back to menu';
            app.Point1 = uipanel(app.UIFigure);
            app.PressurePaEditField_5Label = uilabel(app.Point1);
            app.PressurePaEditField_5Label.Text = 'Pressure (Pa)';
            app.Pressure1 = uieditfield(app.Point1, 'numeric');
            app.Pressure1.ValueDisplayFormat = '%.2f';
            app.Pressure1.ValueChangedFcn = createCallbackFcn(app, @Pressure1ValueChanged, true);
            app.Pressure1.Value = 4000000;
            app.Title1 = uilabel(app.Point1);
            app.Title1.Text = 'Point 1';
            app.PressurePaEditField_5Label_5 = uilabel(app.Point1);
            app.PressurePaEditField_5Label_5.Text = 'Temp (K)';
            app.Temp1 = uieditfield(app.Point1, 'numeric');
            app.Temp1.ValueDisplayFormat = '%.2f';
            app.Temp1.Value = 480;
            app.Point4 = uipanel(app.UIFigure);
            app.PressurePaEditField_5Label_2 = uilabel(app.Point4);
            app.PressurePaEditField_5Label_2.Text = 'Pressure (Pa)';
            app.Pressure4 = uieditfield(app.Point4, 'numeric');
            app.Pressure4.ValueDisplayFormat = '%.2f';
            app.Pressure4.ValueChangedFcn = createCallbackFcn(app, @Pressure4ValueChanged, true);
            app.Pressure4.Value = 4000000;
            app.Title4 = uilabel(app.Point4);
            app.Title4.Text = 'Point 4';
            app.Point4OutLabel = uilabel(app.Point4);
            app.Point4OutLabel.Text = 'TEMP E PHASE';
            app.PointB = uipanel(app.UIFigure);
            app.TempKEditField_6Label = uilabel(app.PointB);
            app.TempKEditField_6Label.Text = 'Temp (K)';
            app.TempB = uieditfield(app.PointB, 'numeric');
            app.TempB.ValueDisplayFormat = '%.2f';
            app.TempB.Value = 300;
            app.B = uilabel(app.PointB);
            app.B.Text = 'B';
            app.PointBOutLabel = uilabel(app.PointB);
            app.PointBOutLabel.Text = 'TEMP E PHASE';
            app.PointA = uipanel(app.UIFigure);
            app.TempKEditField_6Label_2 = uilabel(app.PointA);
            app.TempKEditField_6Label_2.Text = 'Temp (K)';
            app.TempA = uieditfield(app.PointA, 'numeric');
            app.TempA.ValueDisplayFormat = '%.2f';
            app.TempA.Value = 900;
            app.A = uilabel(app.PointA);
            app.A.Text = 'A';
            app.PointAOutLabel = uilabel(app.PointA);
            app.PointAOutLabel.Text = 'TEMP E PHASE';
            app.DestroyedExergy = uipanel(app.UIFigure);
            app.Xd = uilabel(app.DestroyedExergy);
            app.Xd.Text = 'Xd';
            app.DestroyedExergyLabel = uilabel(app.DestroyedExergy);
            app.DestroyedExergyLabel.Text = 'Destroyed Exergy: ';
            app.PointAl = uipanel(app.UIFigure);
            app.TempKEditField_6Label_3 = uilabel(app.PointAl);
            app.TempKEditField_6Label_3.Text = 'Temp (K)';
            app.TempAl = uieditfield(app.PointAl, 'numeric');
            app.TempAl.ValueDisplayFormat = '%.2f';
            app.TempAl.Value = 274;
            app.Al = uilabel(app.PointAl);
            app.Al.Text = 'A''';
            app.PointAlOutLabel = uilabel(app.PointAl);
            app.PointAlOutLabel.Text = 'TEMP E PHASE';
            app.Point2 = uipanel(app.UIFigure);
            app.PressurePaEditField_5Label_3 = uilabel(app.Point2);
            app.PressurePaEditField_5Label_3.Text = 'Pressure (Pa)';
            app.Pressure2 = uieditfield(app.Point2, 'numeric');
            app.Pressure2.ValueDisplayFormat = '%.2f';
            app.Pressure2.ValueChangedFcn = createCallbackFcn(app, @Pressure2ValueChanged, true);
            app.Pressure2.Value = 1000000;
            app.Title2 = uilabel(app.Point2);
            app.Title2.Text = 'Point 2';
            app.Point2OutLabel = uilabel(app.Point2);
            app.Point2OutLabel.Text = 'TEMP E PHASE';
            app.PointBl = uipanel(app.UIFigure);
            app.TempKEditField_6Label_4 = uilabel(app.PointBl);
            app.TempKEditField_6Label_4.Text = 'Temp (K)';
            app.TempBl = uieditfield(app.PointBl, 'numeric');
            app.TempBl.ValueDisplayFormat = '%.2f';
            app.TempBl.Value = 400;
            app.Bl = uilabel(app.PointBl);
            app.Bl.Text = 'B''';
            app.PointBlOutLabel = uilabel(app.PointBl);
            app.PointBlOutLabel.Text = 'TEMP E PHASE';
            app.DestroyedExergy_2 = uipanel(app.UIFigure);
            app.Xd_2 = uilabel(app.DestroyedExergy_2);
            app.Xd_2.Text = 'Xd';
            app.DestroyedExergyLabel_2 = uilabel(app.DestroyedExergy_2);
            app.DestroyedExergyLabel_2.Text = 'Destroyed Exergy: ';
            app.Point3 = uipanel(app.UIFigure);
            app.PressurePaEditField_5Label_4 = uilabel(app.Point3);
            app.PressurePaEditField_5Label_4.Text = 'Pressure (Pa)';
            app.Pressure3 = uieditfield(app.Point3, 'numeric');
            app.Pressure3.ValueDisplayFormat = '%.2f';
            app.Pressure3.ValueChangedFcn = createCallbackFcn(app, @Pressure3ValueChanged, true);
            app.Pressure3.Value = 1000000;
            app.Title3 = uilabel(app.Point3);
            app.Title3.Text = 'Point 3';
            app.Point3OutLabel = uilabel(app.Point3);
            app.Point3OutLabel.Text = 'TEMP E PHASE';
            app.XeLabel = uilabel(app.UIFigure);
            app.XeLabel.Text = 'Xe: ';
            app.XsLabel = uilabel(app.UIFigure);
            app.XsLabel.Text = 'Xs:';
            app.WtLabel = uilabel(app.UIFigure);
            app.WtLabel.Text = 'Wt:';
            app.WpLabel = uilabel(app.UIFigure);
            app.WpLabel.Text = 'Wp:';
            app.XiLabel = uilabel(app.UIFigure);
            app.XiLabel.Text = 'Xi:';
            app.XoLabel = uilabel(app.UIFigure);
            app.XoLabel.Text = 'Xo:';
            app.EvaporatorLabel_2 = uilabel(app.UIFigure);
            app.EvaporatorLabel_2.Text = 'Evaporator:';
            app.TurbineLabel = uilabel(app.UIFigure);
            app.TurbineLabel.Text = 'Turbine:';
            app.CondenserLabel = uilabel(app.UIFigure);
            app.CondenserLabel.Text = 'Condenser';
            app.PumpLabel = uilabel(app.UIFigure);
            app.PumpLabel.Text = 'Pump';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exergy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end