classdef mainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputsTab                   matlab.ui.container.Tab
        EvaporatorLabel             matlab.ui.control.Label
        TurbineLabel                matlab.ui.control.Label
        PumpLabel                   matlab.ui.control.Label
        CondenserLabel              matlab.ui.control.Label
        HeatOutputLabel             matlab.ui.control.Label
        WpLabel                     matlab.ui.control.Label
        WtLabel                     matlab.ui.control.Label
        HeatInputLabel              matlab.ui.control.Label
        CycleImage                  matlab.ui.control.Image
        Point1                      matlab.ui.container.Panel
        Title1                      matlab.ui.control.Label
        Pressure1                   matlab.ui.control.NumericEditField
        PressurePaEditField_6Label  matlab.ui.control.Label
        Temp1                       matlab.ui.control.NumericEditField
        TempKEditField_6Label       matlab.ui.control.Label
        Point4                      matlab.ui.container.Panel
        Point4OutLabel              matlab.ui.control.Label
        Title4                      matlab.ui.control.Label
        Pressure4                   matlab.ui.control.NumericEditField
        PressurePaEditField_5Label  matlab.ui.control.Label
        Point2                      matlab.ui.container.Panel
        Title3_2                    matlab.ui.control.Label
        Point2OutLabel              matlab.ui.control.Label
        Pressure2                   matlab.ui.control.NumericEditField
        PressurePaEditField_4Label  matlab.ui.control.Label
        Point3                      matlab.ui.container.Panel
        Point3OutLabel              matlab.ui.control.Label
        Title3                      matlab.ui.control.Label
        Pressure3                   matlab.ui.control.NumericEditField
        PressurePaEditField_3Label  matlab.ui.control.Label
        InputPanel                  matlab.ui.container.Panel
        BacktomenuButton            matlab.ui.control.Button
        FluidTypeKnobLabel_2        matlab.ui.control.Label
        TurbineEfficiency           matlab.ui.control.NumericEditField
        EfficiencyoftheturbineEditFieldLabel  matlab.ui.control.Label
        PumpEfficiency              matlab.ui.control.NumericEditField
        EfficiencyofthepumpEditFieldLabel  matlab.ui.control.Label
        EfficiencyLabel             matlab.ui.control.Label
        DebugLabel                  matlab.ui.control.Label
        FluidSelected               matlab.ui.control.Label
        MaxFlowRate                 matlab.ui.control.NumericEditField
        TempKEditField_3Label_2     matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        FluidTypeKnob               matlab.ui.control.DiscreteKnob
        FluidTypeKnobLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Cycle:
        cycle_pressure_values = [];
        cycle_temp_values = [];        
        Phase_3 = "";
        Phase_2 = "";
        Phase_4 = ""; % Used to store the point 4 phase
     
           
        % == Initializing variables ==
        canRun = false; % Controls when the runCycle function can be called
        current_fluid = ""; % Stores the current fluid in the app

        % Limits
        P_critic = 0;
        P_min = 0;
        T_critic = 0;

        % Entropy Values:
        H_1 = 0;
        H_2 = 0;
        H_3 = 0;
        H_4 = 0;

        % Temperature Values:
        T_4 = 0;
        T_3 = 0;
        T_2 = 0;

        % Entalpy Values:
        S_1 = 0;  
        S_3 = 0;

    end
    
    properties (Access = private)
        errorwindow; % Description
        initscene;
    end
    
    methods (Access = public)
        
        %% Check inputs from cycle to verify their states
        function check_cycle_inputs(app)

            if app.Pressure2.Value >= app.Pressure1.Value 
                throw_err(app, "Pressure in point 2 is greater or equal than point 1.");
            
            % If the inputs are right, then the app can run
            else
                app.canRun = true;
            end

        end
        function gotomainmenu(app)
            app.initscene = app1;
        end
        
        %% Used to reset values displayed at screen
        % They just set their text strings to "", making them invisible
        function reset_values(app)
            app.EfficiencyLabel.Text = "";
            app.Point4OutLabel.Text = "";
            app.Point3OutLabel.Text = "";
            app.Point2OutLabel.Text = "";
            app.HeatOutputLabel.Text = "";
            app.HeatInputLabel.Text = "";
            app.WpLabel.Text = "";
            app.WtLabel.Text = "";

        end
        %% Used to throw an error message with an given text
        % rankineErrorMessage corresponds to the name of the acessory app
        % present in the same folder that app is localized
        function throw_err(app, text)
            app.errorwindow = rankineErrorMessage;
            import_data(app.errorwindow, text);

        end
        
        %% Used to setup limits on the app for pressure and temperaure
        % That method handles the change of edit fields limits
        function setup_limits(app)
             % Setupping the cycle limits:
             app.P_critic = py.CoolProp.CoolProp.PropsSI("Pcrit", app.current_fluid);
             app.P_min = round(py.CoolProp.CoolProp.PropsSI("pmin", app.current_fluid), 2);

             % Setup presure limits
             for pressure = app.cycle_pressure_values
                 pressure.Limits = [app.P_min app.P_critic];
             end
            
        end
        
        %% Used to show the resut values of the cycle
        % The value calculated in the app is showed in labels on the screen
        function show_out(app, Wt, Heat_out, Wp, Heat_in, Eff)
            if contains(string(app.Phase_3), "unknown")
                app.Phase_3 = "Saturated Liquid";
            end
            app.Point3OutLabel.Text = "Temp = " + num2str(app.T_3, "%.2f") + "K" + newline + "Phase: " + string(app.Phase_3);
            app.Point2OutLabel.Text = "Temp = " + num2str(app.T_2, "%.2f") + "K" + newline + "Phase: " + string(app.Phase_2);
            app.Point4OutLabel.Text = "Temp = " + num2str(app.T_4, "%.2f") +"K" + newline + " Phase: " + string(app.Phase_4);
            app.HeatOutputLabel.Text = " Heat Output = " + num2str(Heat_out, "%.2f") + "KJ/s";
            app.HeatInputLabel.Text = "Heat Input = " + num2str(Heat_in, "%.2f") + "KJ/s";
            app.WpLabel.Text = "Wp = " + num2str(Wp, "%.2f") + "KJ/s";
            app.WtLabel.Text = "Wt = " + num2str(Wt, "%.2f") + "KJ/s";
            app.EfficiencyLabel.Visible = "on";
            app.EfficiencyLabel.Text = "Efficiency: " + num2str(Eff, "%.2f") + "%";
            app.EfficiencyLabel.Visible = "on";

        end
        
        %% Used for run the cycle
        % Does all of calculation with given user input data and shows the
        % result on the screen
        function run_cycle(app)
            try
                %% Point 1

                app.H_1 = py.CoolProp.CoolProp.PropsSI("H", "P", app.Pressure1.Value, "T", app.Temp1.Value, app.current_fluid);

                app.S_1 = py.CoolProp.CoolProp.PropsSI("S", "P", app.Pressure1.Value, "T", app.Temp1.Value, app.current_fluid);
                
                %% Point 2
                app.H_2 = py.CoolProp.CoolProp.PropsSI("H", "P", app.Pressure2.Value, "S", app.S_1, app.current_fluid);
                app.T_2 = py.CoolProp.CoolProp.PropsSI("T", "P", app.Pressure2.Value, "S", app.S_1, app.current_fluid);

                app.Phase_2 = py.CoolProp.CoolProp.PhaseSI("P", app.Pressure2.Value, "S", app.S_1, app.current_fluid);
    
                %% Point 3
                app.H_3 = py.CoolProp.CoolProp.PropsSI("H", "P", app.Pressure3.Value,"Q", 0, app.current_fluid);

                app.S_3 = py.CoolProp.CoolProp.PropsSI("S", "P", app.Pressure3.Value, "Q", 0, app.current_fluid);

                app.T_3 = py.CoolProp.CoolProp.PropsSI("T", "P", app.Pressure3.Value,"S", app.S_3, app.current_fluid);

                app.Phase_3 = py.CoolProp.CoolProp.PhaseSI("P", app.Pressure3.Value, "T", app.T_3, app.current_fluid);
    
                %% Point 4
                app.H_4 = py.CoolProp.CoolProp.PropsSI("H", "P", app.Pressure4.Value, "S", app.S_3, app.current_fluid);

                app.T_4 = py.CoolProp.CoolProp.PropsSI("T", "P", app.Pressure4.Value, "S", app.S_3, app.current_fluid);

                app.Phase_4 = py.CoolProp.CoolProp.PhaseSI("P", app.Pressure4.Value, "S", app.S_3, app.current_fluid);

                check_cycle_inputs(app);

           
            catch ERR
                 % This try-catch statement is used for catch external errors
                 % and print then in own error window
                 % Is useful because some CoolProp operations may result on
                 % a error, which breaks the application

                if(ERR.identifier == "MATLAB:Python:PyException")
                    throw_err(app, "An Python/CoolProp Error ocurred, possibly the values inserted are not possible! " + ...
                        "Error:PyException");
                    return;
                end
                throw_err(app, "Critical error!" + ...
                    "Error: Matlab Exception");
                ERR.rethrow(ERR);
             end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.cycle_pressure_values = [app.Pressure1  app.Pressure2  app.Pressure3  app.Pressure4];
            app.cycle_temp_values = [app.Temp1  app.T_2  app.T_3  app.T_4];
            reset_values(app);
            app.UIFigure.Position = [200 0 1310 860];
            app.UIFigure.Name = "RANKINE CYCLE";
            app.current_fluid = app.FluidTypeKnob.Value;
            app.FluidSelected.Text = "Selected value: " + num2str(app.current_fluid);
            app.RunButton.Icon = "";
            setup_limits(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            reset_values(app);
            run_cycle(app);

            Vmax = app.MaxFlowRate.Value;
            Eff_T = app.TurbineEfficiency.Value;
            Eff_P = app.PumpEfficiency.Value;

            if app.canRun
                app.DebugLabel.Text = "DONE!";

                % Calculating the Heat and Work
                Wt = Vmax * (app.H_1 - app.H_2) *(Eff_T/100) / 1000; % KJ/s
                Heat_out = Vmax * (app.H_2 - app.H_3) / 1000; % KJ/s
                Wp = Vmax * (app.H_4 - app.H_3)*(Eff_P/100) / 1000; % KJ/s
                Heat_in = Vmax * (app.H_1 - app.H_4) / 1000; % KJ/s

                % Calculating the Efficiency
                Eff = (Wt - Wp) * (100) / Heat_in; % (%)
                
                % Showing out the results
                show_out(app, Wt, Heat_out, Wp, Heat_in, Eff);

            else
                app.DebugLabel.Text = "ERROR!";
            end

            app.canRun = false; % Setted to can run the app again
          
        end

        % Value changed function: Pressure4
        function Pressure4ValueChanged(app, event)
            value = app.Pressure4.Value;
            app.Pressure1.Value = value;
        end

        % Value changed function: Pressure1
        function Pressure1ValueChanged(app, event)
            value = app.Pressure1.Value;
            app.Pressure4.Value = value;
        end

        % Value changed function: FluidTypeKnob
        function FluidTypeKnobValueChanged(app, event)
            value = app.FluidTypeKnob.Value;
            app.current_fluid = value;
            setup_limits(app);
            app.FluidSelected.Text = "Selected Value: " + num2str(value);
        end

        % Value changed function: Pressure3
        function Pressure3ValueChanged(app, event)
            value = app.Pressure3.Value;
            app.Pressure2.Value = value;
        end

        % Value changed function: Pressure2
        function Pressure2ValueChanged(app, event)
            value = app.Pressure2.Value;
            app.Pressure3.Value = value;
        end

        % Button pushed function: BacktomenuButton
        function BacktomenuButtonPushed(app, event)
            gotomainmenu(app);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputPanel = uipanel(app.InputsTab);
            app.FluidTypeKnobLabel = uilabel(app.InputPanel);
            app.FluidTypeKnobLabel.Text = 'Fluid Type';
            app.FluidTypeKnob = uiknob(app.InputPanel, 'discrete');
            app.FluidTypeKnob.Items = {'R11', 'R245fa', 'Water'};
            app.FluidTypeKnob.ValueChangedFcn = createCallbackFcn(app, @FluidTypeKnobValueChanged, true);
            app.FluidTypeKnob.Tooltip = {''};
            app.FluidTypeKnob.Value = 'R11';
            app.RunButton = uibutton(app.InputPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'RankineCycle.jpeg');
            app.RunButton.Tooltip = {'Runs the cycle'};
            app.RunButton.Text = 'Run!';
            app.TempKEditField_3Label_2 = uilabel(app.InputPanel);
            app.TempKEditField_3Label_2.Text = 'Maximum Flow Rate';
            app.MaxFlowRate = uieditfield(app.InputPanel, 'numeric');
            app.MaxFlowRate.ValueDisplayFormat = '%.2f';
            app.MaxFlowRate.Value = 300;
            app.FluidSelected = uilabel(app.InputPanel);
            app.FluidSelected.Text = 'Valor Selecionado: R11';
            app.DebugLabel = uilabel(app.InputPanel);
            app.DebugLabel.Text = 'DEBUG';
            app.EfficiencyLabel = uilabel(app.InputPanel);
            app.EfficiencyLabel.Text = 'Efficiency: ';
            app.EfficiencyofthepumpEditFieldLabel = uilabel(app.InputPanel);
            app.EfficiencyofthepumpEditFieldLabel.Text = 'Efficiency of the pump:';
            app.PumpEfficiency = uieditfield(app.InputPanel, 'numeric');
            app.PumpEfficiency.Limits = [0 100];
            app.PumpEfficiency.Value = 100;
            app.EfficiencyoftheturbineEditFieldLabel = uilabel(app.InputPanel);
            app.EfficiencyoftheturbineEditFieldLabel.Text = 'Efficiency of the turbine:';
            app.TurbineEfficiency = uieditfield(app.InputPanel, 'numeric');
            app.TurbineEfficiency.Limits = [0 100];
            app.TurbineEfficiency.Value = 100;
            app.FluidTypeKnobLabel_2 = uilabel(app.InputPanel);
            app.FluidTypeKnobLabel_2.Text = 'Rankine Cycle';
            app.BacktomenuButton = uibutton(app.InputPanel, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = 'Back to menu';
            app.Point3 = uipanel(app.InputsTab);
            app.PressurePaEditField_3Label = uilabel(app.Point3);
            app.PressurePaEditField_3Label.Text = 'Pressure (Pa)';
            app.Pressure3 = uieditfield(app.Point3, 'numeric');
            app.Pressure3.ValueDisplayFormat = '%.2f';
            app.Pressure3.ValueChangedFcn = createCallbackFcn(app, @Pressure3ValueChanged, true);
            app.Pressure3.Value = 6000;
            app.Title3 = uilabel(app.Point3);
            app.Title3.Text = 'Point 3';
            app.Point3OutLabel = uilabel(app.Point3);
            app.Point3OutLabel.Text = 'TEMP E X';
            app.Point2 = uipanel(app.InputsTab);
            app.PressurePaEditField_4Label = uilabel(app.Point2);
            app.PressurePaEditField_4Label.Text = 'Pressure (Pa)';
            app.Pressure2 = uieditfield(app.Point2, 'numeric');
            app.Pressure2.ValueDisplayFormat = '%.2f';
            app.Pressure2.ValueChangedFcn = createCallbackFcn(app, @Pressure2ValueChanged, true);
            app.Pressure2.Value = 6000;
            app.Point2OutLabel = uilabel(app.Point2);
            app.Point2OutLabel.Text = 'TEMP';
            app.Title3_2 = uilabel(app.Point2);
            app.Title3_2.Text = 'Point 2';
            app.Point4 = uipanel(app.InputsTab);
            app.PressurePaEditField_5Label = uilabel(app.Point4);
            app.PressurePaEditField_5Label.Text = 'Pressure (Pa)';
            app.Pressure4 = uieditfield(app.Point4, 'numeric');
            app.Pressure4.ValueDisplayFormat = '%.2f';
            app.Pressure4.ValueChangedFcn = createCallbackFcn(app, @Pressure4ValueChanged, true);
            app.Pressure4.Value = 4000000;
            app.Title4 = uilabel(app.Point4);
            app.Title4.Text = 'Point 4';
            app.Point4OutLabel = uilabel(app.Point4);
            app.Point4OutLabel.Text = 'TEMP E PHASE';
            app.Point1 = uipanel(app.InputsTab);
            app.TempKEditField_6Label = uilabel(app.Point1);
            app.TempKEditField_6Label.Text = 'Temp (K)';
            app.Temp1 = uieditfield(app.Point1, 'numeric');
            app.Temp1.ValueDisplayFormat = '%.4f';
            app.Temp1.Value = 480;
            app.PressurePaEditField_6Label = uilabel(app.Point1);
            app.PressurePaEditField_6Label.Text = 'Pressure (Pa)';
            app.Pressure1 = uieditfield(app.Point1, 'numeric');
            app.Pressure1.ValueDisplayFormat = '%.2f';
            app.Pressure1.ValueChangedFcn = createCallbackFcn(app, @Pressure1ValueChanged, true);
            app.Pressure1.Value = 4000000;
            app.Title1 = uilabel(app.Point1);
            app.Title1.Text = 'Point 1';
            app.CycleImage = uiimage(app.InputsTab);
            app.CycleImage.ImageSource = fullfile(pathToMLAPP, 'RankineCycle.jpeg');
            app.HeatInputLabel = uilabel(app.InputsTab);
            app.HeatInputLabel.Text = 'Heat Input: ';
            app.WtLabel = uilabel(app.InputsTab);
            app.WtLabel.Text = 'Wt = ';
            app.WpLabel = uilabel(app.InputsTab);
            app.WpLabel.Text = 'Wp = ';
            app.HeatOutputLabel = uilabel(app.InputsTab);
            app.HeatOutputLabel.Text = 'Heat Output = ';
            app.CondenserLabel = uilabel(app.InputsTab);
            app.CondenserLabel.Text = 'Condenser';
            app.PumpLabel = uilabel(app.InputsTab);
            app.PumpLabel.Text = 'Pump';
            app.TurbineLabel = uilabel(app.InputsTab);
            app.TurbineLabel.Text = 'Turbine';
            app.EvaporatorLabel = uilabel(app.InputsTab);
            app.EvaporatorLabel.Text = 'Evaporator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end